classdef Free_Last_fm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        HourlyTab               matlab.ui.container.Tab
        Panel_Hour              matlab.ui.container.Panel
        ArtistsTab              matlab.ui.container.Tab
        Panel                   matlab.ui.container.Panel
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        LoadButton              matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.Panel_Hour);
            
            polarplot(app.Pax,0,0)
            % Direction and Orientation
            set(app.Pax,'ThetaZeroLocation','top',...
                    'ThetaDir','clockwise');
            % Axis theta
            angles = 0:360/24:360;
            app.Pax.ThetaTick = angles;
            labels = cell(1,24);
            for c=1:length(labels)
                n = c-1;
                labels{c} = num2str(n);
            end
            labels{1} = '24';
            app.Pax.ThetaTickLabel = labels;
            % Axis r
            for c=1:length(app.Pax.RTickLabel)
                app.Pax.RTickLabel{c} = '';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            MUSICS = load_musics('Zeee_as-a-bee.json');
            
            musicPerHour = zeros(24,1);

            %% Results
            for c=1:length(MUSICS.track)
                h = hour(MUSICS.track(c).date);
                musicPerHour(h+1) = musicPerHour(h+1) +1;
            end
            RESULTS.Artist  = retrieves_unique_artists(MUSICS.track);
            RESULTS.Album   = retrieves_unique_albuns(MUSICS.track);
            RESULTS.Music   = retrieves_unique_music(MUSICS.track);
            
            %% For the plot
            
            SETUP.title = "Number of songs per hour frame";
            % Hours
            plot_hours(app.Pax, musicPerHour, SETUP);
            
            %% Artist
            %SETUP.title = "Top 10 Artists";
            %plot_stems(app.UIAxes_2, RESULTS.Artist, SETUP);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HourlyTab = uitab(app.TabGroup);
            app.HourlyTab.Title = 'Hourly';
            app.Panel_Hour = uipanel(app.HourlyTab);
            app.ArtistsTab = uitab(app.TabGroup);
            app.ArtistsTab.Title = 'Artists';
            app.Panel = uipanel(app.UIFigure);
            app.FileNameEditFieldLabel = uilabel(app.Panel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.Panel, 'text');
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Lamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Free_Last_fm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end